classdef RB_solver_for_SRN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RaoBlackwellsolverforLabel      matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        NetworkTab                      matlab.ui.container.Tab
        IndexEditField                  matlab.ui.control.NumericEditField
        IndexEditFieldLabel             matlab.ui.control.Label
        ValueofthechosenparameterEditField  matlab.ui.control.NumericEditField
        ValueofthechosenparameterEditFieldLabel  matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        DeletethelastreactionButton     matlab.ui.control.Button
        Label_error_information         matlab.ui.control.Label
        AdditionalparametersDropDown    matlab.ui.control.DropDown
        AdditionalparametersDropDownLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        NumberofreactionsLabel          matlab.ui.control.Label
        EditFieldProdcuts               matlab.ui.control.EditField
        ReactionconstantEditField       matlab.ui.control.NumericEditField
        ReactionconstantEditFieldLabel  matlab.ui.control.Label
        AddthereactionButton            matlab.ui.control.Button
        PropensityifnotamassactionreactionEditField  matlab.ui.control.EditField
        PropensityifnotamassactionreactionLabel  matlab.ui.control.Label
        ProductsspeciesarerepresentedbyS1S2Label  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ReactantsspeciesarerepresentedbyS1S2Label  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EditFieldReactants              matlab.ui.control.EditField
        MassactionkineticsCheckBox      matlab.ui.control.CheckBox
        UITableReactions                matlab.ui.control.Table
        NumberofadditionalparametersSpinner  matlab.ui.control.Spinner
        NumberofadditionalparametersSpinnerLabel  matlab.ui.control.Label
        NumberofspeciesSpinner          matlab.ui.control.Spinner
        NumberofspeciesSpinnerLabel     matlab.ui.control.Label
        NameofthenetworkEditField       matlab.ui.control.EditField
        NameofthenetworkEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        StoredNetworksDropDownLabel     matlab.ui.control.Label
        StoredNetworksDropDown          matlab.ui.control.DropDown
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ObservationTab                  matlab.ui.container.Tab
        ObservationsY_ith_iXtsigmaW_itLabel  matlab.ui.control.Label
        DeletethelastobservationchannelButton  matlab.ui.control.Button
        Label_error_information2        matlab.ui.control.Label
        SavethenetworkButton            matlab.ui.control.Button
        AddtheobservationButton         matlab.ui.control.Button
        EditFieldHFunction              matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        UITableReactions_2              matlab.ui.control.Table
        NoiseintensitystandardvarianceEditField  matlab.ui.control.NumericEditField
        NoiseintensitystandardvarianceEditFieldLabel  matlab.ui.control.Label
        ObservationchannelEditField     matlab.ui.control.NumericEditField
        ObservationchannelEditFieldLabel  matlab.ui.control.Label
        UITableObservation              matlab.ui.control.Table
        NameofthenetworkLabel           matlab.ui.control.Label
        SimulationoftheprocessTab       matlab.ui.container.Tab
        Label_error_information_6       matlab.ui.control.Label
        ObservationscollectedeveryEditField  matlab.ui.control.NumericEditField
        ObservationscollectedeveryEditFieldLabel  matlab.ui.control.Label
        UITableInitialConditions        matlab.ui.control.Table
        TerminalTimeEditField           matlab.ui.control.NumericEditField
        TerminalTimeEditFieldLabel      matlab.ui.control.Label
        ObservationchannelDropDown      matlab.ui.control.DropDown
        ObservationchannelDropDownLabel  matlab.ui.control.Label
        StateDropDown                   matlab.ui.control.DropDown
        StateDropDownLabel              matlab.ui.control.Label
        SimulatetheprocessButton        matlab.ui.control.Button
        Label_6                         matlab.ui.control.Label
        Label_error_information_5       matlab.ui.control.Label
        GenerateMatlabscriptsforthenetworkButton  matlab.ui.control.Button
        NameofthenetworkLabel_3         matlab.ui.control.Label
        UIAxesObservationSpecific       matlab.ui.control.UIAxes
        UIAxesObservations              matlab.ui.control.UIAxes
        UIAxesStates                    matlab.ui.control.UIAxes
        UIAxesStatesSpecific            matlab.ui.control.UIAxes
        FSPMCmethodfortheCMETab         matlab.ui.container.Tab
        ShowtheFSPresultButton          matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        GenerateMatlabscriptsforCMEsolversButton  matlab.ui.control.Button
        SavetheresultButton             matlab.ui.control.Button
        UpdatethesettingButton_decomposition_2  matlab.ui.control.Button
        ShowtheMonteCarloresultButton   matlab.ui.control.Button
        TruncationSizeLabel             matlab.ui.control.Label
        UITableTS_2                     matlab.ui.control.Table
        NameofthenetworkLabel_4         matlab.ui.control.Label
        FSPmethodButton                 matlab.ui.control.Button
        Label_error_information_7       matlab.ui.control.Label
        TerminalTimeEditField_2         matlab.ui.control.NumericEditField
        TerminalTimeEditField_2Label    matlab.ui.control.Label
        EachspeciesareassumedtohavetheinitialdistributionLabel  matlab.ui.control.Label
        InitialDistributionLabel        matlab.ui.control.Label
        UITableInitialDistribution      matlab.ui.control.Table
        SamplesizeEditField             matlab.ui.control.NumericEditField
        SamplesizeEditFieldLabel        matlab.ui.control.Label
        MCmethodButton                  matlab.ui.control.Button
        ConditionalindependencedecompositionTab  matlab.ui.container.Tab
        Label_error_information_4       matlab.ui.control.Label
        FollowersubsystemsLabel         matlab.ui.control.Label
        UITableFollower                 matlab.ui.control.Table
        UITableLeader                   matlab.ui.control.Table
        DothedecompositionofthenetworkButton  matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        SavethenetworkButton_2          matlab.ui.control.Button
        Label_error_information_3       matlab.ui.control.Label
        ThesizeofeachfollowersubsystemshouldbelessthanEditField  matlab.ui.control.NumericEditField
        ThesizeofeachfollowersubsystemshouldbelessthanEditFieldLabel  matlab.ui.control.Label
        UpdatethesettingButton_decomposition  matlab.ui.control.Button
        UITableTS                       matlab.ui.control.Table
        NameofthenetworkLabel_2         matlab.ui.control.Label
        RBCMEsolverTab                  matlab.ui.container.Tab
        Label_error_information_8       matlab.ui.control.Label
        EachspeciesareassumedtohavetheinitialdistributionLabel_2  matlab.ui.control.Label
        InitialDistributionLabel_2      matlab.ui.control.Label
        UITableInitialDistribution_2    matlab.ui.control.Table
        UpdatethesettingButton_decomposition_5  matlab.ui.control.Button
        UpdatethesettingButton_decomposition_4  matlab.ui.control.Button
        UpdatethesettingButton_decomposition_3  matlab.ui.control.Button
        GenerateMatlabscriptsfortheRBCMEsolverButton  matlab.ui.control.Button
        TerminalTimeEditField_3         matlab.ui.control.NumericEditField
        TerminalTimeEditField_3Label    matlab.ui.control.Label
        SamplesizeEditField_2           matlab.ui.control.NumericEditField
        SamplesizeEditField_2Label      matlab.ui.control.Label
        TruncationSizeLabel_2           matlab.ui.control.Label
        UITableTS_3                     matlab.ui.control.Table
        NameofthenetworkLabel_5         matlab.ui.control.Label
        StochasticfilterTab             matlab.ui.container.Tab
        TerminalTimeEditField_4         matlab.ui.control.NumericEditField
        TerminalTimeEditField_4Label    matlab.ui.control.Label
        Label_error_information_9       matlab.ui.control.Label
        UpdatethesettingButton_decomposition_6  matlab.ui.control.Button
        ObservationsLabel               matlab.ui.control.Label
        NameofthenetworkLabel_6         matlab.ui.control.Label
        UITableObservation_2            matlab.ui.control.Table
        ObservationscollectedeveryEditField_2  matlab.ui.control.NumericEditField
        ObservationscollectedeveryEditField_2Label  matlab.ui.control.Label
        UITableTS_4                     matlab.ui.control.Table
        SamplesizeEditField_3           matlab.ui.control.NumericEditField
        SamplesizeEditField_3Label      matlab.ui.control.Label
        EachspeciesareassumedtohavetheinitialdistributionLabel_3  matlab.ui.control.Label
        InitialDistributionLabel_3      matlab.ui.control.Label
        UITableInitialDistribution_3    matlab.ui.control.Table
        TruncationSizeLabel_3           matlab.ui.control.Label
    end

    
    properties (Access = public)
        name=string(['']);        % name of the network
        num_species=0;  % number of species
        num_reactions=0; %number of reactions
        reactant_vector=[]; % the vectors of reactant complexes
        product_vector=[];  % the vectors of product complexes
        k=[];               % the reaction constants for mass-action kinetics
        size_phi=0;         % the number of additional parameters
        phi=[];             % the values of the additional parameters
        reaction_type=[];   % the type of reactions, it is 1 if it is a mass-action type, otherwise 0.
        propensity=string([]);      % the propensity function
        h=string([]);               % the ideal observation channel. 
        sigma=[];                   % noise intensity
       
        %properties for the conditional independence decomposition
        truncated_size=[];          % The size of the truncated space for each species
        threshold_follower_subsystem=0;     % the maximum size of follower subsystems
        species_decomposition_LF=[];        % indicating whether a speciesleongs to a leader (0) or follower(1) system.
        follower_subsystems=[{}];         % indicating which follower subsystem it belongs to (0 for leader level species)
        reaction_decomposition_LF=[];       % 0 for leader level reactions, 1 for follower-level reactions
        reaction_decomposition_F=[];        % indicating which subsystem influences this reaction; 0 if none. 
        Size_of_truncated_space=[];         % showing the size of the truncated space for each follower subsystem. 
        
        % properties for the single trajectory simulation
        X0=[];  % Initial condtion
        FT=0;   % the final time of the simulation
        Observation_period=0;  % 1/ observation frequency 
        TX=[]; X=[]; % the time and the state of the single process
        TY=[]; Y=[]; % the time and the value of the observation process.
        
        % properties for CME solvers
        IniDistribution_Mean=[];
        IniDistribution_Variance=[];
        sample_size=1;
        d_MC=[];                     % the distribution obtained by the Monte Carlo method
        MC_samples=[];               % the simulated samples
        d_FSP=[];
        X_RB=[];                     % the particles generated by the RB method
        P_RB=[];                     % the marginal probability generated by the RB method
        
    end


    methods (Access = public)
        
        % Display the reactions on page 1
        function DisplayReactions(app)
               addpath(genpath('./')); % add path    
               app.NameofthenetworkEditField.Value=app.name; % show the name of the network
               app.NameofthenetworkLabel.Text=strcat("Name of the network: ", app.name);
               app.NameofthenetworkLabel_2.Text=strcat("Name of the network: ", app.name);
               app.NameofthenetworkLabel_3.Text=strcat("Name of the network: ", app.name);
               app.NameofthenetworkLabel_4.Text=strcat("Name of the network: ", app.name);
               app.NameofthenetworkLabel_5.Text=strcat("Name of the network: ", app.name);
               app.NameofthenetworkLabel_6.Text=strcat("Name of the network: ", app.name);
               app.NumberofspeciesSpinner.Value=app.num_species; % show the number of species
               app.Label_4.Text=string(app.num_reactions); % show the number of reactions
               app.NumberofadditionalparametersSpinner.Value=app.size_phi; % show the number of additional parameters
               
               % show the value of additional parameters  
               app.NumberofadditionalparametersSpinnerValueChanged;
               
               % show the reaction information
               if app.num_reactions>0
                   T=Prepare_network_information_for_display(app.num_reactions,app.reactant_vector,app.product_vector,app.reaction_type,app.k,app.propensity);
               else
                   T=[];
               end
               app.UITableReactions.Data=T;
               app.UITableReactions_2.Data=T;
               app.Label_error_information.Text='';
               
               %show the observation information
               if size(app.h,2)>0
                   for i=1:size(app.h,2)
                       Observation_channel(i)=strcat("Y",string(i));
                   end
                   T=table(Observation_channel',app.h',app.sigma');
               else
                   T=[];
               end
               app.UITableObservation.Data=T;
               app.Label_error_information2.Text='';
               
               % update the decomposition information
               app.ThesizeofeachfollowersubsystemshouldbelessthanEditField.Value=app.threshold_follower_subsystem;
               for i=1:app.num_species
                     Species_column(i)=strcat("S",string(i));
                     if size(app.truncated_size,1)>=i
                         TS(i)=app.truncated_size(i);
                     else
                         TS(i)=0;
                     end
                     
               end
               T=table(Species_column',TS');
               app.UITableTS.Data=T;
               app.Label_error_information_3.Text='';
               app.Label_error_information_4.Text='';
               
               % update the information for simulations
               app.TerminalTimeEditField.Value=app.FT;
               app.ObservationscollectedeveryEditField.Value=app.Observation_period;
               T=table(Species_column',app.X0);
               app.UITableInitialConditions.Data=T;
               app.StateDropDown.Items=Species_column;
               if size(app.h,2)==0
                   observation_column=[{}];
               else
                   for i=1:size(app.h,2)
                      observation_column(i)=strcat("Y",string(i));
                   end
               end
               app.ObservationchannelDropDown.Items=observation_column;
               app.Label_error_information_5.Text='';
               app.Label_error_information_6.Text='';
               
               % update the information for FSP/MC method for CME and the
               % RB-CME solver
               % update the stochastic filter
               T=table(Species_column',TS');
               app.UITableTS_2.Data=T;
               app.UITableTS_3.Data=T;
               app.UITableTS_4.Data=T;
               if size(app.IniDistribution_Mean,1)<app.num_species  % The initial distribution has not been updated
                   app.IniDistribution_Mean=zeros(app.num_species,1);
                   app.IniDistribution_Variance=zeros(app.num_species,1);
               end
               T=table(Species_column',app.IniDistribution_Mean,app.IniDistribution_Variance);
               app.UITableInitialDistribution.Data=T;
               app.UITableInitialDistribution_2.Data=T;
               app.UITableInitialDistribution_3.Data=T;
               app.TerminalTimeEditField_2.Value=app.FT;
               app.SamplesizeEditField.Value=app.sample_size;
               app.Label_error_information_7.Text='';
               app.TerminalTimeEditField_3.Value=app.FT;
               app.SamplesizeEditField_2.Value=app.sample_size;
               app.Label_error_information_8.Text='';
               app.TerminalTimeEditField_4.Value=app.FT;
               app.SamplesizeEditField_3.Value=app.sample_size;
               app.ObservationscollectedeveryEditField_2.Value=app.Observation_period;
               app.Label_error_information_9.Text='';
               y_size=size(app.h,2);
               if app.Observation_period>0 && y_size>0
                   yt=[app.Observation_period:app.Observation_period:app.FT]; % observation time
                   clear T;
                   T(:,1)=table(yt');
                   if size(app.Y,2)~=size(yt,2) || size(app.Y,1)~=y_size 
                       y=zeros(y_size,app.FT/app.Observation_period);
                   else
                       y=app.Y;
                   end
                   for i=1:y_size
                      app.UITableObservation_2.ColumnName{i+1}=strcat("Y",string(i)); 
                      T(:,i+1)=table((y(i,:))');
                      app.UITableObservation_2.ColumnEditable(i+1)=1; 
                   end
 %                  T=table(yt',y');
               else
                   T=table([]);
               end
               app.UITableObservation_2.Data=T;
                   
               rmpath(genpath('./'));
        end


        function Display_the_conditional_independence_decomposition(app)
            addpath(genpath('./')); % add path  
            
            % Display the leader system
            Leader_level_species="";
            for i=1:app.num_species
                if app.species_decomposition_LF(i)==0
                    if strlength(Leader_level_species)>0
                        Leader_level_species=strcat(Leader_level_species,", ");
                    end
                    Leader_level_species=strcat(Leader_level_species,"S",string(i));
                end
            end
            Leader_level_reactions="";
            for j=1:app.num_reactions
                if app.reaction_decomposition_LF(j)==0
                   if strlength(Leader_level_reactions)>0
                        Leader_level_reactions=strcat(Leader_level_reactions,", ");
                    end
                    Leader_level_reactions=strcat(Leader_level_reactions, "R",string(j));
                end
            end
            T=table(Leader_level_species',Leader_level_reactions');
            app.UITableLeader.Data=T;
            
            
            % Display follower subsystems
            if size(app.follower_subsystems,2)==0 % if there is no follower-level reactions
                app.UITableFollower.Data=[];
                rmpath(genpath('./'));
                return;
            end
            index=1:size(app.follower_subsystems,2);
            index=strcat("Z",string(index));
            for i=1:size(app.follower_subsystems,2)
                string_help=strcat("S",string(app.follower_subsystems{i}));
                species_involved(i)=join(string_help,', ');
            end
            follower_level_reactions=cell(1,size(app.follower_subsystems,2));
            leader_level_reactions=cell(1,size(app.follower_subsystems,2));
            for j=1:app.num_reactions
                subsystem_index=app.reaction_decomposition_F(j);
                if subsystem_index>0  % it belongs to some follower subsystem 
                  if app.reaction_decomposition_LF(j)==0 % a leader level reaction
                     leader_level_reactions{subsystem_index}...
                         =strcat(leader_level_reactions{subsystem_index}," R", string(j),",");
                  else
                     follower_level_reactions{subsystem_index}...
                         =strcat(follower_level_reactions{subsystem_index}," R",string(j),",");
                  end  
                end
            end
            Observations_involved=cell(1,size(app.follower_subsystems,2));
            v=species_measured_by_observation_channels(app.num_species,app.h);
            for j=1:size(v,2)
                for i=1:size(app.follower_subsystems,2)
                   if all(size(intersect(app.follower_subsystems{i},v{j})))>0   % the i-th subsystem measured by j
                       Observations_involved{i}=strcat(Observations_involved{i}, " Y", string(j),',');
                   end 
                end
            end

            
            T=table(index',species_involved',follower_level_reactions',...
                   leader_level_reactions',Observations_involved',app.Size_of_truncated_space');
            app.UITableFollower.Data=T;
            rmpath(genpath('./'));
        end


        function Generate_Matlab_scripts_for_single_trajectory_simulations(app)
            % delete old files
            delete Single_trajectory_simulator/propensity_function.m
            delete Single_trajectory_simulator/h_function.m
            addpath(genpath('./')); % add path
            % Generate propensity functions for single trajectory simulation
            propensity_function_generator(app.propensity,app.name);
            h_function_generator(app.h,app.name);
            rmpath(genpath('./'));
        end
        
        function Generate_Matlab_scripts_for_CME_solvers(app)
            addpath(genpath('./')); % add path
            % scripts for the Monte Carlo method
            propensity_function_generator_for_multi_trajectories(app.propensity,app.name);
            propensity_function_generator(app.propensity,app.name);
            h_function_generator(app.h,app.name);
            % scripts for the RB method
            propensity_generator_for_RB(app.propensity,app.name);
            rmpath(genpath('./'));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: StoredNetworksDropDown
        function StoredNetworksDropDownOpening(app, event)
            filelist=dir('./Networks/*.mat'); %search for the stored networks
            num_stored_networks=size(filelist,1); % number of the stored networks
            item_name=cell(1,num_stored_networks+1);
            for i=1:num_stored_networks
                item_name{i}=extractBefore(filelist(i).name,'.'); % change the name of the items
            end
            item_name{num_stored_networks+1}=app.StoredNetworksDropDown.Items{size(app.StoredNetworksDropDown.Items,2)}; % the 'nonthing selected' option
            app.StoredNetworksDropDown.Items=item_name;
        end

        % Value changed function: StoredNetworksDropDown
        function StoredNetworksDropDownValueChanged(app, event)
            value = app.StoredNetworksDropDown.Value;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if strcmp(app.StoredNetworksDropDown.Value, app.StoredNetworksDropDown.Items{size(app.StoredNetworksDropDown.Items,2)})==0 % something is selected 
               addpath(genpath('./')); % add path            
               file_name=strcat('./Networks/',app.StoredNetworksDropDown.Value,'.mat');
               sigma=0; % avoid the confusion of Matlab
               load(file_name); % load the network selected;
               app.name=app.StoredNetworksDropDown.Value;
               app.num_species=num_species;
               app.num_reactions=num_reactions; 
               app.reactant_vector=reactant_vector;
               app.product_vector=product_vector;
               app.k=k;
               app.size_phi=size_phi;
               if size_phi==0
                  app.phi=[];
               else
                  app.phi=phi;
               end
               app.reaction_type=reaction_type;
               app.propensity=propensity;
               app.h=h;
               app.sigma=sigma;
               app.truncated_size=truncated_size;
               app.threshold_follower_subsystem=threshold_follower_subsystem;
               app.FT=FT;
               app.Observation_period=observation_period;
               app.X0=X0;
               app.IniDistribution_Mean=IniDistribution_Mean;
               app.IniDistribution_Variance=IniDistribution_Variance;
               app.sample_size=sample_size;
               app.d_MC=[];          
               app.MC_samples=[];              
               rmpath(genpath('./'));
               
               
               % Display the reactions
               DisplayReactions(app)
               
               app.Label_error_information.Text=strcat(app.name, ' loaded.');
              
            end
        end

        % Value changed function: 
        % NumberofadditionalparametersSpinner
        function NumberofadditionalparametersSpinnerValueChanged(app, event)
            value = app.NumberofadditionalparametersSpinner.Value;
            app.size_phi=value;
               if app.size_phi>=size(app.phi,2)
                for i=size(app.phi,2)+1:app.size_phi
                   app.phi(i)=0;
                end
               else
                   app.phi=app.phi(1,1:app.size_phi);
               end
            % show the value of additional parameters 
            additional_parameter_list=cell(1,app.size_phi);
            for i=1:app.size_phi
                   additional_parameter_list{i}=strcat('phi(',int2str(i),')');
            end
            app.AdditionalparametersDropDown.Items=additional_parameter_list;
            app.AdditionalparametersDropDownValueChanged;  % show the value of the parameter
        end

        % Value changed function: AdditionalparametersDropDown
        function AdditionalparametersDropDownValueChanged(app, event)
            if app.size_phi>0 % check whether there are additional parameters.
                value = app.AdditionalparametersDropDown.Value;
                index=extractAfter(value,'phi'); % find out which additional parameter is chosen
                index=str2num(index);
                app.ValueofthechosenparameterEditField.Value=app.phi(index);  % show the value of the chosen parameter
            else
                app.ValueofthechosenparameterEditField.Value=0;  % no additional paramter
            end
        end

        % Value changed function: ValueofthechosenparameterEditField
        function ValueofthechosenparameterEditFieldValueChanged(app, event)
            phi_value = app.ValueofthechosenparameterEditField.Value;
            value = app.AdditionalparametersDropDown.Value;
            if size(value,1)>0
               index=extractAfter(value,'phi'); % find out which additional parameter is chosen
               index=str2num(index);
            end
            app.phi(index)=phi_value;   % change the value of the corresponding phi
        end

        % Button pushed function: DeletethelastreactionButton
        function DeletethelastreactionButtonPushed(app, event)
               app.num_reactions=max(app.num_reactions-1,0);
               app.reactant_vector=app.reactant_vector(:,1:app.num_reactions);
               app.product_vector=app.product_vector(:,1:app.num_reactions);
               app.k=app.k(1:app.num_reactions);
               app.reaction_type=app.reaction_type(1:app.num_reactions);
               app.propensity=app.propensity(1:app.num_reactions);
               % Display the reactions
               DisplayReactions(app)
               app.Label_error_information.Text="The last reaction deleted."
        end

        % Button pushed function: AddthereactionButton
        function AddthereactionButtonPushed(app, event)
            addpath(genpath('./')); % add path    
            app.Label_error_information.Text='';
            
            new_index=int64(app.IndexEditField.Value);
            if new_index<=0 
               app.Label_error_information.Text='The index needs to be a positive integer.';
               return;
            end
            
            Rstring=string(app.EditFieldReactants.Value);
            Pstring=string(app.EditFieldProdcuts.Value);
            [new_reactant, checkR]=reactant_product_string_to_vector(app.num_species,Rstring);
            [new_product,  checkP]=reactant_product_string_to_vector(app.num_species,Pstring);
            if checkR==0 
               app.Label_error_information.Text='Errors in reactants.';
               return; 
            end
            if checkP==0 
               app.Label_error_information.Text='Errors in products.';
               return; 
            end
            if size(new_reactant,1)>app.num_species
               app.Label_error_information.Text='Undefined species in reactants. Please reset the total number of species';
               return;
            end
            if size(new_product,1)>app.num_species
               app.Label_error_information.Text='Undefined species in products. Please reset the total number of species';
               return;
            end    
            
            new_reaction_type=app.MassactionkineticsCheckBox.Value;
            if new_reaction_type==0
                new_k=0;
            else
                new_k=app.ReactionconstantEditField.Value;
                if new_k==0
                    app.Label_error_information.Text='Please type in the reaction constant value.';
                    return;
                end
            end
            
            if new_reaction_type==0
               new_propensity=string(app.PropensityifnotamassactionreactionEditField.Value);
               if strcmp(new_propensity,"")==1
                  app.Label_error_information.Text='Please type in the propensity.';
                  return;
               end
            else
                new_propensity=Mass_action_propensity_generator(new_index,new_reactant);
            end
            
            % add the new reaction
            if new_index>app.num_reactions 
               app.num_reactions=new_index; % update the number of reactions
            end
            app.reactant_vector(:,new_index)=new_reactant;
            app.product_vector(:,new_index)=new_product;
            app.k(new_index,1)=new_k;
            app.reaction_type(new_index,1)=new_reaction_type;
            app.propensity(new_index)=new_propensity;
            rmpath(genpath('./'));
            
            % display the information.
            DisplayReactions(app);
            app.Label_error_information.Text='The new reaction added.';
            
            % clear the inputs
            app.IndexEditField.Value=0;
            app.EditFieldReactants.Value="";
            app.EditFieldProdcuts.Value="";
            app.MassactionkineticsCheckBox.Value=0;
            app.ReactionconstantEditField.Value=0;
            app.PropensityifnotamassactionreactionEditField.Value="";
            
        end

        % Value changed function: NameofthenetworkEditField
        function NameofthenetworkEditFieldValueChanged(app, event)
            value = app.NameofthenetworkEditField.Value;
            app.name=value;
        end

        % Value changed function: NumberofspeciesSpinner
        function NumberofspeciesSpinnerValueChanged(app, event)
            new_num_species = app.NumberofspeciesSpinner.Value;
            if new_num_species<=0
                app.Label_error_information.Text='The number of species should be positive.';
                return;
            end
            
            if new_num_species<app.num_species
                app.num_species=new_num_species;
                app.X0=app.X0(1:app.num_species);
                app.IniDistribution_Mean=app.IniDistribution_Mean(1:app.num_species);
                app.IniDistribution_Variance=app.IniDistribution_Variance(1:app.num_species);
                if app.num_reactions>0
                   app.reactant_vector=app.reactant_vector(1:app.num_species,:);
                   app.product_vector=app.product_vector(1:app.num_species,:);  
                end
            elseif new_num_species> app.num_species 
                app.X0=[app.X0;zeros(new_num_species-app.num_species,1)];
                app.IniDistribution_Mean=[app.IniDistribution_Mean;zeros(new_num_species-app.num_species,1)];
                app.IniDistribution_Variance=[app.IniDistribution_Variance;zeros(new_num_species-app.num_species,1)];
                if app.num_reactions>0
                   app.reactant_vector(app.num_species+1:new_num_species,:)=zeros(new_num_species-app.num_species,app.num_reactions);
                   app.product_vector(app.num_species+1:new_num_species,:)=zeros(new_num_species-app.num_species,app.num_reactions); 
                end
                app.num_species=new_num_species;
            end
            addpath(genpath('./')); % add path 
            for i=1:app.num_reactions
                if app.reaction_type==1 
                   app.propensity(i)=Mass_action_propensity_generator(i,app.reactant_vector(:,i));
                end
            end
            rmpath(genpath('./'));
            
            % display the information.
            DisplayReactions(app);
            app.Label_error_information.Text='The number of species updated.';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            name=app.name;
            num_species=app.num_species;
            num_reactions=app.num_reactions;
            reactant_vector=app.reactant_vector;
            product_vector=app.product_vector;
            k=app.k;
            size_phi=app.size_phi;
            if size_phi==0
                 phi=[];
            else
                 phi=app.phi;
            end  
            reaction_type=app.reaction_type;
            propensity=app.propensity;
            h=app.h;
            sigma=app.sigma;
            truncated_size=app.truncated_size;          
            threshold_follower_subsystem=app.threshold_follower_subsystem;  
            FT=app.FT;
            observation_period=app.Observation_period;
            X0=app.X0;
            IniDistribution_Mean=app.IniDistribution_Mean;
            IniDistribution_Variance=app.IniDistribution_Variance;
            sample_size=app.sample_size;
               
            % save to the disk
            filename=strcat('./Networks/',app.name);
            if strcmp(app.name,'')==0
                 save(filename,'name','num_species','num_reactions','reactant_vector','product_vector',...
                 'k','size_phi','phi','reaction_type','propensity','h','sigma','truncated_size',...
                 'threshold_follower_subsystem',"FT","observation_period","X0","IniDistribution_Mean",...
                 "IniDistribution_Variance",'sample_size');
                  app.Label_error_information.Text='Saving succeeded.';
                  app.Label_error_information2.Text='Saving succeeded.';
                  app.Label_error_information_3.Text='Saving succeeded.';
            else
                  app.Label_error_information.Text='Please type in the network name.';
                  app.Label_error_information2.Text='Please type in the network name.';
                  app.Label_error_information_3.Text='Please type in the network name.';
            end

        end

        % Button pushed function: SavethenetworkButton
        function SavethenetworkButtonPushed(app, event)
            app.SaveButtonPushed;
        end

        % Button pushed function: AddtheobservationButton
        function AddtheobservationButtonPushed(app, event)
            observation_index=int64(app.ObservationchannelEditField.Value);
            if observation_index<=0
                app.Label_error_information2.Text='The observation channel needs to be a positive integer.';
                return;
            end
            new_h=app.EditFieldHFunction.Value;
            if size(new_h,2)==0
                app.Label_error_information2.Text='Please type in the function h.';
                return;
            end
            new_sigma=app.NoiseintensitystandardvarianceEditField.Value;
            if new_sigma<=0
                app.Label_error_information2.Text='Noise intensity should be positive.';
                return;                
            end
            app.h(observation_index)=new_h;
            app.sigma(observation_index)=new_sigma;
            
            % display the information.
            DisplayReactions(app);
            app.Label_error_information.Text='The new observation channel is added.';
            
            % reset the inputs
            app.ObservationchannelEditField.Value=0;
            app.EditFieldHFunction.Value='';
            app.NoiseintensitystandardvarianceEditField.Value=0;
        end

        % Button pushed function: 
        % DeletethelastobservationchannelButton
        function DeletethelastobservationchannelButtonPushed(app, event)
            num_observations=size(app.h,2);
            if num_observations>1
                app.h=app.h(1:num_observations-1);
                app.sigma=app.sigma(1:num_observations-1);
            else
                app.h=string([]);
                app.sigma=[];
            end
            
            % display the information.
            DisplayReactions(app);
            app.Label_error_information2.Text='The last observation channel removed.';            
        end

        % Button pushed function: 
        % UpdatethesettingButton_decomposition
        function UpdatethesettingButton_decompositionPushed(app, event)
            % update truncated size
            truncated_size=table2array(app.UITableTS.Data(:,2));
            truncated_size=int64(truncated_size);
            if min(truncated_size)<=0
                app.Label_error_information_3.Text='The size of truncation should be a positive integer.';
                return;
            end
            
            %update the threshold
            threshold_follower_subsystem=app.ThesizeofeachfollowersubsystemshouldbelessthanEditField.Value;
            if threshold_follower_subsystem<=0
                app.Label_error_information_3.Text='The threshold for the size of follower subsystems should be positive.';
                return;
            end
            
            app.truncated_size=truncated_size;
            app.threshold_follower_subsystem=threshold_follower_subsystem;
            
            % display
            DisplayReactions(app);
            app.Label_error_information_3.Text='The setting updated.';     
            
        end

        % Button pushed function: SavethenetworkButton_2
        function SavethenetworkButton_2Pushed(app, event)
            app.SaveButtonPushed;
        end

        % Button pushed function: 
        % DothedecompositionofthenetworkButton
        function DothedecompositionofthenetworkButtonPushed(app, event)
            % decompose the network
            
            if size(app.truncated_size,2)<app.num_species && size(app.truncated_size,1)<app.num_species
                app.Label_error_information_4.Text='Please type in the information of truncation.'
                return;
            end
            
            addpath(genpath('./')); % add path  
            if app.num_species>10
                [app.species_decomposition_LF,app.follower_subsystems,app.reaction_decomposition_LF,...
                 app.reaction_decomposition_F,app.Size_of_truncated_space]...
                =conditional_independence_decomposition_greedy(app.num_species,app.reactant_vector,...
                app.product_vector,app.propensity,app.h,app.truncated_size,app.threshold_follower_subsystem);
                %app.Label_error_information_4.Text='The algorithm only works for networks with less than 20 species.'
                %return;
            else 
                [app.species_decomposition_LF,app.follower_subsystems,app.reaction_decomposition_LF,...
                 app.reaction_decomposition_F,app.Size_of_truncated_space]...
                =conditional_independence_decomposition(app.num_species,app.reactant_vector,...
                app.product_vector,app.propensity,app.h,app.truncated_size,app.threshold_follower_subsystem);
            end
            rmpath(genpath('./'))    
            
            % Display
            app.Display_the_conditional_independence_decomposition;
            app.Label_error_information_4.Text='The decomposition succeeded.';
        end

        % Button pushed function: 
        % GenerateMatlabscriptsforthenetworkButton
        function GenerateMatlabscriptsforthenetworkButtonPushed(app, event)
             Generate_Matlab_scripts_for_single_trajectory_simulations(app);
             app.Label_error_information_5.Text='Matlab scripts generated';
        end

        % Button pushed function: SimulatetheprocessButton
        function SimulatetheprocessButtonPushed(app, event)
            app.FT=app.TerminalTimeEditField.Value;
            app.Observation_period=app.ObservationscollectedeveryEditField.Value;
            X0=table2array(app.UITableInitialConditions.Data(:,2));
            app.X0=double(int64(X0));
            
            if app.FT<=0
               app.Label_error_information_6.Text='Please type in a positive terminal time.';
               return;
            end
            
            if app.Observation_period<=0 && size(app.h,2)>0
                app.Label_error_information_6.Text='The observation frequency should be positive.';
                return;
            end
            
            if min(app.X0)<0
                app.Label_error_information_6.Text='The initial conditions should be positive.';
                return;
            end
            
            
            app.GenerateMatlabscriptsforthenetworkButtonPushed; % generate Matlab stripts
            
            %  Display the setting
            DisplayReactions(app);
            
            % run the simulation 
            addpath(genpath('./')); % add path 
            [app.TX,app.X]=next_reaction_method_single_trajectory...
               (app.X0,app.k,app.phi,app.FT,app.reactant_vector,app.product_vector);
            [app.TY,app.Y]=observations(app.TX,app.X,app.h,app.sigma,app.Observation_period);
            rmpath(genpath('./'));
            
            % Display the result
            % plot the evolution of the system state
            TX_display(1:2:2*size(app.TX,2)-1)=app.TX;
            TX_display(2:2:2*size(app.TX,2)-1)=app.TX(2:size(app.TX,2));
            X_display(:,1:2:2*size(app.TX,2)-1)=app.X;
            X_display(:,2:2:2*size(app.TX,2)-1)=app.X(:,1:size(app.X,2)-1);
            plot(app.UIAxesStates,TX_display,X_display);
            for i=1:app.num_species
                species_name(i)=strcat("$S_",string(i),"$");
            end
            legend(app.UIAxesStates,species_name,'Interpreter','latex');
            app.StateDropDownValueChanged;
            
            %plot observations
            if size(app.h,2)>0
               plot(app.UIAxesObservations,app.TY,app.Y,'o--');
               for i=1:size(app.h,2)
                   observation_name(i)=strcat("$Y_",string(i),"$");
               end
               legend(app.UIAxesObservations,observation_name,'Interpreter','latex');
               app.ObservationchannelDropDownValueChanged;
               axis(app.UIAxesObservations,[0 app.FT 0 max(max(app.Y))]);
            else
               plot(app.UIAxesObservations,[0,app.FT],[0,0],'white'); % a blank board
               plot(app.UIAxesObservationSpecific,[0,app.FT],[0,0],'white');
            end
            
            %  Display the setting
            DisplayReactions(app);
            
            
            app.Label_error_information_6.Text='Simulation finished.';
            
        end

        % Button down function: UIAxesStatesSpecific
        function UIAxesStatesSpecificButtonDown(app, event)
            % extract the index of the species
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)
            value = app.StateDropDown.Value;
            index=extractAfter(value,'S'); % find out which species is chosen
            index=str2num(index);
            
            if size(app.TX,2)>0
                 TX_display(1:2:2*size(app.TX,2)-1)=app.TX;
                 TX_display(2:2:2*size(app.TX,2)-1)=app.TX(2:size(app.TX,2));
                 X_display(:,1:2:2*size(app.TX,2)-1)=app.X;
                 X_display(:,2:2:2*size(app.TX,2)-1)=app.X(:,1:size(app.X,2)-1);
                 plot(app.UIAxesStatesSpecific,TX_display,X_display(index,:));
            end
            
        end

        % Value changed function: ObservationchannelDropDown
        function ObservationchannelDropDownValueChanged(app, event)
            value = app.ObservationchannelDropDown.Value;
            index=extractAfter(value,'Y'); % find out which species is chosen
            index=str2num(index);
            
            if size(app.TY,2)>0
                 plot(app.UIAxesObservationSpecific,app.TY,app.Y(index,:),'o--');
                 axis(app.UIAxesObservationSpecific,[0 app.FT 0 max(app.Y(index,:))]);
            end
        end

        % Button pushed function: 
        % UpdatethesettingButton_decomposition_2
        function UpdatethesettingButton_decomposition_2Pushed(app, event)
            % update truncated size
            truncated_size=table2array(app.UITableTS_2.Data(:,2));
            truncated_size=int64(truncated_size);
            if min(truncated_size)<=0
                app.Label_error_information_7.Text='The size of truncation should be a positive integer.';
                return;
            end
            
            % final time
            FT=app.TerminalTimeEditField_2.Value;
            if FT<0
                app.Label_error_information_7.Text='The terminal time should be a positive integer.';
                return;
            end
            
            % Initial distribution
            Ini_mean=table2array(app.UITableInitialDistribution.Data(:,2));
            Ini_Variance=table2array(app.UITableInitialDistribution.Data(:,3));
            if min(Ini_mean-Ini_Variance)<0
                app.Label_error_information_7.Text='The mean should be greater than the variance.';
                return;
            end
            if min([Ini_mean;Ini_Variance])<0
                app.Label_error_information_7.Text='The mean and variance should be greater than the variance.';
                return;
            end
            
            if app.SamplesizeEditField.Value<=0
                app.Label_error_information_7.Text='The sample size should be a positive integer.';
                return;
            end
            
            app.truncated_size=truncated_size;
            app.FT=FT;
            app.IniDistribution_Mean=Ini_mean;
            app.IniDistribution_Variance=Ini_Variance;      
            app.sample_size=floor(app.SamplesizeEditField.Value);
            
            % Display
            DisplayReactions(app);
            app.Label_error_information_7.Text='The setting updated.';
            
        end

        % Button pushed function: 
        % GenerateMatlabscriptsforCMEsolversButton
        function GenerateMatlabscriptsforCMEsolversButtonPushed(app, event)
            app.UpdatethesettingButton_decomposition_2Pushed; % update the setting 
            
            if strcmp(app.Label_error_information_7.Text,'The setting updated.')==0
                app.Label_error_information_7.Text='Please update the setting first.';
                return;
            end
            
            Generate_Matlab_scripts_for_CME_solvers(app);
            app.Label_error_information_7.Text='Matlab scripts generated.';
        end

        % Button pushed function: MCmethodButton
        function MCmethodButtonPushed(app, event)
            app.GenerateMatlabscriptsforCMEsolversButtonPushed(app); % generate Matlab scripts
            
            if strcmp(app.Label_error_information_7.Text,'Matlab scripts generated.')==0
                app.Label_error_information_7.Text='Please generate Matlab scripts first.';
                return;
            end
            
            if prod(app.truncated_size)>10^7 && app.sample_size>10^7
                app.Label_error_information_7.Text='For large systems, the sample size should be less than 10 million.';
                return;
            end
            
            addpath(genpath('./')); % add path
            f=msgbox('The Monte Carlo method has been started.');
            if prod(app.truncated_size)<10^7
                % If the system size is small, we summarize the
                % distribution according to the truncated space
                app.d_MC=Monte_Carlo_CME_solver...
                        (app.k,app.phi,app.FT,app.reactant_vector,app.product_vector,...
                         app.IniDistribution_Mean,app.IniDistribution_Variance,...
                         app.sample_size,app.truncated_size);
            else
                % If the system size is large, we do simulation only
                if gpuDeviceCount>=1
                   [TX,app.MC_samples]=next_reaction_method_multi_trajectories...
                                    (app.k,app.phi,app.FT,app.reactant_vector,app.product_vector,...
                                     app.IniDistribution_Mean,app.IniDistribution_Variance,...
                                     app.sample_size);
                else
                    [TX,app.MC_samples]=next_reaction_method_multi_trajectories_no_GPU...
                                    (app.k,app.phi,app.FT,app.reactant_vector,app.product_vector,...
                                     app.IniDistribution_Mean,app.IniDistribution_Variance,...
                                     app.sample_size);
                end
            end
            delete(f);
            f=msgbox('The Monte Carlo method has been finished. \^v^/');
            app.Label_error_information_7.Text='The Monte Carlo method finished.';
            rmpath(genpath('./'))
            
        end

        % Button pushed function: ShowtheMonteCarloresultButton
        function ShowtheMonteCarloresultButtonPushed(app, event)
                 if size(app.d_MC,1)>1 || size(app.MC_samples,2)==app.sample_size
                     addpath(genpath('./')); % add path         
                     plot_result(app.d_MC,app.MC_samples)
                     rmpath(genpath('./'));
                     app.Label_error_information_7.Text='The Monte Carlo result plotted.';
                 else
                     app.Label_error_information_7.Text='Please run the MC method first.';
                     return;
                 end
        end

        % Button pushed function: FSPmethodButton
        function FSPmethodButtonPushed(app, event)
            app.UpdatethesettingButton_decomposition_2Pushed; % update the setting 
            
            if strcmp(app.Label_error_information_7.Text,'The setting updated.')==0
                app.Label_error_information_7.Text='Please update the setting first.';
                return;
            end
            
            if prod(app.truncated_size)>=10^7
                app.Label_error_information_7.Text='The size of the system is too large. The FSP algorithm can only work for systems having less than 10 million states.';
                return;               
            end
            
            % generate Matlab scripts
            Generate_Matlab_scripts_for_single_trajectory_simulations(app); 
            
            % run the FSP algorithm 
            addpath(genpath('./')); % add path
            s1='The FSP algorithm has been started. It may take a few minutes or hours. Please wait patiently.';
            s2='If you want to stop it, please press Ctrl and C in the Matlab command window.';
            f=msgbox(strcat(s1,s2));
            app.d_FSP=FSP_Power_Series(app.k,app.phi,app.reactant_vector,app.product_vector,...
                                       app.FT,...
                                       app.IniDistribution_Mean,...
                                       app.IniDistribution_Variance,...
                                       app.truncated_size);
            delete(f);
            f=msgbox('The FSP algorithm has been finished. \^v^/');
            rmpath(genpath('./'));
            app.Label_error_information_7.Text='The FSP algorithm has been finished.';
            
            
        end

        % Button pushed function: ShowtheFSPresultButton
        function ShowtheFSPresultButtonPushed(app, event)
                if size(app.d_FSP,1)>1 
                     addpath(genpath('./')); % add path         
                     plot_result(app.d_FSP,[])
                     rmpath(genpath('./'));
                     app.Label_error_information_7.Text='The FSP result plotted.';
                 else
                     app.Label_error_information_7.Text='Please run the FSP algorithm first.';
                     return;
                 end
        end

        % Button pushed function: SavetheresultButton
        function SavetheresultButtonPushed(app, event)
            % the network information 
            name=app.name;
            num_species=app.num_species;
            num_reactions=app.num_reactions;
            reactant_vector=app.reactant_vector;
            product_vector=app.product_vector;
            k=app.k;
            size_phi=app.size_phi;
            if size_phi==0
                 phi=[];
            else
                 phi=app.phi;
            end  
            reaction_type=app.reaction_type;
            propensity=app.propensity;
            h=app.h;
            sigma=app.sigma;
            truncated_size=app.truncated_size;          
            threshold_follower_subsystem=app.threshold_follower_subsystem;  
            FT=app.FT;
            observation_period=app.Observation_period;
            X0=app.X0;
            IniDistribution_Mean=app.IniDistribution_Mean;
            IniDistribution_Variance=app.IniDistribution_Variance;
            sample_size=app.sample_size;
            
            % the distribution 
            d_FSP=app.d_FSP;
            d_MC=app.d_MC;
            MC_samples=app.MC_samples;
            
            filename=strcat('./Saved_distributions/',app.name);
            if strcmp(app.name,'')==0
                 save(filename,'name','num_species','num_reactions','reactant_vector','product_vector',...
                 'k','size_phi','phi','reaction_type','propensity','h','sigma','truncated_size',...
                 'threshold_follower_subsystem',"FT","observation_period","X0","IniDistribution_Mean",...
                 "IniDistribution_Variance",'sample_size','d_FSP',"d_MC","MC_samples");
                  app.Label_error_information_7.Text='The distributions succeeded.';
            else
                  app.Label_error_information.Text='Please type in the network name.';
                  app.Label_error_information2.Text='Please type in the network name.';
                  app.Label_error_information_3.Text='Please type in the network name.';
                  app.Label_error_information_7.Text='Please type in the network name.';
            end
            
        end

        % Button pushed function: 
        % UpdatethesettingButton_decomposition_3
        function UpdatethesettingButton_decomposition_3Pushed(app, event)
            % update truncated size
            truncated_size=table2array(app.UITableTS_3.Data(:,2));
            truncated_size=int64(truncated_size);
            if min(truncated_size)<=0
                app.Label_error_information_8.Text='The size of truncation should be a positive integer.';
                return;
            end
            
            % final time
            FT=app.TerminalTimeEditField_3.Value;
            if FT<0
                app.Label_error_information_8.Text='The terminal time should be a positive integer.';
                return;
            end
            
            % Initial distribution
            Ini_mean=table2array(app.UITableInitialDistribution_2.Data(:,2));
            Ini_Variance=table2array(app.UITableInitialDistribution_2.Data(:,3));
            if min(Ini_mean-Ini_Variance)<0
                app.Label_error_information_8.Text='The mean should be greater than the variance.';
                return;
            end
            if min([Ini_mean;Ini_Variance])<0
                app.Label_error_information_8.Text='The mean and variance should be greater than the variance.';
                return;
            end
            
            if app.SamplesizeEditField_2.Value<=0
                app.Label_error_information_8.Text='The sample size should be a positive integer.';
                return;
            end
            
            app.truncated_size=truncated_size;
            app.FT=FT;
            app.IniDistribution_Mean=Ini_mean;
            app.IniDistribution_Variance=Ini_Variance;      
            app.sample_size=floor(app.SamplesizeEditField_2.Value);
            
            % Display
            DisplayReactions(app);
            app.Label_error_information_8.Text='The setting updated.';
        end

        % Button pushed function: 
        % GenerateMatlabscriptsfortheRBCMEsolverButton
        function GenerateMatlabscriptsfortheRBCMEsolverButtonPushed(app, event)
            app.UpdatethesettingButton_decomposition_3Pushed; % update the setting 
            
            if strcmp(app.Label_error_information_8.Text,'The setting updated.')==0
                app.Label_error_information_7.Text='Please update the setting first.';
                return;
            end
            
            Generate_Matlab_scripts_for_CME_solvers(app);
            app.Label_error_information_8.Text='Matlab scripts generated.';
        end

        % Button pushed function: 
        % UpdatethesettingButton_decomposition_4
        function UpdatethesettingButton_decomposition_4Pushed(app, event)
            app.GenerateMatlabscriptsfortheRBCMEsolverButtonPushed(app); % generate Matlab scripts
            
            if strcmp(app.Label_error_information_8.Text,'Matlab scripts generated.')==0
                app.Label_error_information_8.Text='Please generate Matlab scripts first.';
                return;
            end
            
            if max(size(app.species_decomposition_LF))<app.num_species
                app.Label_error_information_8.Text='Please do the conditional independence decomposition first.';
                return;
            end
            
            addpath(genpath('./')); % add path
            f=msgbox('The RB-CME solver has been started.');
            [app.X_RB,app.P_RB]=RB_CME_solver...
                 (app.k,app.phi,app.FT,app.num_species,app.reactant_vector,app.product_vector,...
                  app.follower_subsystems,...
                  app.reaction_decomposition_LF,app.reaction_decomposition_F,...
                  app.species_decomposition_LF,...
                  app.IniDistribution_Mean,app.IniDistribution_Variance,...
                  app.sample_size,app.truncated_size,app.propensity);
            delete(f);
            f=msgbox('The RB method has been finished. \^v^/');
            app.Label_error_information_8.Text='The RB-CME solver finished.';
            rmpath(genpath('./'))
            
            
        end

        % Button pushed function: 
        % UpdatethesettingButton_decomposition_5
        function UpdatethesettingButton_decomposition_5Pushed(app, event)
                 if size(app.X_RB,2)==app.sample_size
                     addpath(genpath('./')); % add path         
                     plot_RB_result(app.X_RB,app.P_RB,app.truncated_size,app.follower_subsystems,...
                        app.species_decomposition_LF);
                     rmpath(genpath('./'));
                     app.Label_error_information_8.Text='The result of the RB-CME solver plotted.';
                 else
                     app.Label_error_information_8.Text='Please run the RB-CME solver first.';
                     return;
                 end
        end

        % Button pushed function: 
        % UpdatethesettingButton_decomposition_6
        function UpdatethesettingButton_decomposition_6Pushed(app, event)
            % update truncated size
            truncated_size=table2array(app.UITableTS_4.Data(:,2));
            truncated_size=int64(truncated_size);
            if min(truncated_size)<=0
                app.Label_error_information_9.Text='The size of truncation should be a positive integer.';
                return;
            end
            
            % final time
            FT=app.TerminalTimeEditField_4.Value;
            if FT<0
                app.Label_error_information_9.Text='The terminal time should be a positive integer.';
                return;
            end
            
            % Initial distribution
            Ini_mean=table2array(app.UITableInitialDistribution_3.Data(:,2));
            Ini_Variance=table2array(app.UITableInitialDistribution_3.Data(:,3));
            if min(Ini_mean-Ini_Variance)<0
                app.Label_error_information_9.Text='The mean should be greater than the variance.';
                return;
            end
            if min([Ini_mean;Ini_Variance])<0
                app.Label_error_information_9.Text='The mean and variance should be greater than the variance.';
                return;
            end
            
            if app.SamplesizeEditField_3.Value<=0
                app.Label_error_information_9.Text='The sample size should be a positive integer.';
                return;
            end
            
            % Observation
            ty=table2array(app.UITableObservation_2.Data(:,1));
            y=table2array(app.UITableObservation_2.Data(:,2:end));
            
            app.truncated_size=truncated_size;
            app.FT=FT;
            app.IniDistribution_Mean=Ini_mean;
            app.IniDistribution_Variance=Ini_Variance;      
            app.sample_size=floor(app.SamplesizeEditField_3.Value);
            app.TY=ty';
            app.Y=y';
            
            % Display
            DisplayReactions(app);
            app.Label_error_information_9.Text='The setting updated.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NetworkTab = uitab(app.TabGroup);
            app.NetworkTab.Title = 'Network';
            app.LoadButton = uibutton(app.NetworkTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.NetworkTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StoredNetworksDropDown = uidropdown(app.NetworkTab);
            app.StoredNetworksDropDown.Items = {'Not selected'};
            app.StoredNetworksDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StoredNetworksDropDownOpening, true);
            app.StoredNetworksDropDown.ValueChangedFcn = createCallbackFcn(app, @StoredNetworksDropDownValueChanged, true);
            app.StoredNetworksDropDown.Value = 'Not selected';
            app.StoredNetworksDropDownLabel = uilabel(app.NetworkTab);
            app.StoredNetworksDropDownLabel.Text = 'Stored Networks';
            app.Label = uilabel(app.NetworkTab);
            app.Label.Text = '_______________________________________________________________________________________________________________________________________________';
            app.NameofthenetworkEditFieldLabel = uilabel(app.NetworkTab);
            app.NameofthenetworkEditFieldLabel.Text = 'Name of the network';
            app.NameofthenetworkEditField = uieditfield(app.NetworkTab, 'text');
            app.NameofthenetworkEditField.ValueChangedFcn = createCallbackFcn(app, @NameofthenetworkEditFieldValueChanged, true);
            app.NumberofspeciesSpinnerLabel = uilabel(app.NetworkTab);
            app.NumberofspeciesSpinnerLabel.Text = 'Number of species';
            app.NumberofspeciesSpinner = uispinner(app.NetworkTab);
            app.NumberofspeciesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofspeciesSpinnerValueChanged, true);
            app.NumberofadditionalparametersSpinnerLabel = uilabel(app.NetworkTab);
            app.NumberofadditionalparametersSpinnerLabel.Text = 'Number of additional parameters';
            app.NumberofadditionalparametersSpinner = uispinner(app.NetworkTab);
            app.NumberofadditionalparametersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofadditionalparametersSpinnerValueChanged, true);
            app.UITableReactions = uitable(app.NetworkTab);
            app.UITableReactions.ColumnName = {'Reaction index'; 'Reactants'; 'Products'; 'Reaction type'; 'Reaction Constants'; 'Propensity'};
            app.UITableReactions.RowName = {};
            app.MassactionkineticsCheckBox = uicheckbox(app.NetworkTab);
            app.MassactionkineticsCheckBox.Text = 'Mass-action kinetics';
            app.EditFieldReactants = uieditfield(app.NetworkTab, 'text');
            app.Label_2 = uilabel(app.NetworkTab);
            app.ReactantsspeciesarerepresentedbyS1S2Label = uilabel(app.NetworkTab);
            app.ReactantsspeciesarerepresentedbyS1S2Label.Text = 'Reactants (species are represented by S1, S2, ....)';
            app.Label_3 = uilabel(app.NetworkTab);
            app.Label_3.Text = '------------>';
            app.ProductsspeciesarerepresentedbyS1S2Label = uilabel(app.NetworkTab);
            app.ProductsspeciesarerepresentedbyS1S2Label.Text = 'Products (species are represented by S1, S2, ....)';
            app.PropensityifnotamassactionreactionLabel = uilabel(app.NetworkTab);
            app.PropensityifnotamassactionreactionLabel.Text = 'Propensity (if not a mass-action reaction) ';
            app.PropensityifnotamassactionreactionEditField = uieditfield(app.NetworkTab, 'text');
            app.AddthereactionButton = uibutton(app.NetworkTab, 'push');
            app.AddthereactionButton.ButtonPushedFcn = createCallbackFcn(app, @AddthereactionButtonPushed, true);
            app.AddthereactionButton.Text = 'Add the reaction';
            app.ReactionconstantEditFieldLabel = uilabel(app.NetworkTab);
            app.ReactionconstantEditFieldLabel.Text = 'Reaction constant';
            app.ReactionconstantEditField = uieditfield(app.NetworkTab, 'numeric');
            app.EditFieldProdcuts = uieditfield(app.NetworkTab, 'text');
            app.NumberofreactionsLabel = uilabel(app.NetworkTab);
            app.NumberofreactionsLabel.Text = 'Number of reactions:';
            app.Label_4 = uilabel(app.NetworkTab);
            app.Label_4.Text = '0';
            app.AdditionalparametersDropDownLabel = uilabel(app.NetworkTab);
            app.AdditionalparametersDropDownLabel.Text = 'Additional parameters';
            app.AdditionalparametersDropDown = uidropdown(app.NetworkTab);
            app.AdditionalparametersDropDown.Items = {};
            app.AdditionalparametersDropDown.ValueChangedFcn = createCallbackFcn(app, @AdditionalparametersDropDownValueChanged, true);
            app.AdditionalparametersDropDown.Value = {};
            app.Label_error_information = uilabel(app.NetworkTab);
            app.Label_error_information.Text = '';
            app.DeletethelastreactionButton = uibutton(app.NetworkTab, 'push');
            app.DeletethelastreactionButton.ButtonPushedFcn = createCallbackFcn(app, @DeletethelastreactionButtonPushed, true);
            app.DeletethelastreactionButton.Text = 'Delete the last reaction';
            app.Label2 = uilabel(app.NetworkTab);
            app.Label2.Text = 'Copy numer are represented by X(1), X(2), ....,  additional parameters are represented by phi(1), phi(2), ...., and the function should be written in Matlab language. ';
            app.ValueofthechosenparameterEditFieldLabel = uilabel(app.NetworkTab);
            app.ValueofthechosenparameterEditFieldLabel.Text = 'Value of the chosen parameter';
            app.ValueofthechosenparameterEditField = uieditfield(app.NetworkTab, 'numeric');
            app.ValueofthechosenparameterEditField.ValueChangedFcn = createCallbackFcn(app, @ValueofthechosenparameterEditFieldValueChanged, true);
            app.IndexEditFieldLabel = uilabel(app.NetworkTab);
            app.IndexEditFieldLabel.Text = 'Index';
            app.IndexEditField = uieditfield(app.NetworkTab, 'numeric');
            app.ObservationTab = uitab(app.TabGroup);
            app.ObservationTab.Title = 'Observation';
            app.NameofthenetworkLabel = uilabel(app.ObservationTab);
            app.NameofthenetworkLabel.Text = 'Name of the network: ';
            app.UITableObservation = uitable(app.ObservationTab);
            app.UITableObservation.ColumnName = {'Observations'; 'h function'; 'Noise intensity'};
            app.UITableObservation.RowName = {};
            app.ObservationchannelEditFieldLabel = uilabel(app.ObservationTab);
            app.ObservationchannelEditFieldLabel.Text = 'Observation channel';
            app.ObservationchannelEditField = uieditfield(app.ObservationTab, 'numeric');
            app.NoiseintensitystandardvarianceEditFieldLabel = uilabel(app.ObservationTab);
            app.NoiseintensitystandardvarianceEditFieldLabel.Text = 'Noise intensity (standard variance)';
            app.NoiseintensitystandardvarianceEditField = uieditfield(app.ObservationTab, 'numeric');
            app.UITableReactions_2 = uitable(app.ObservationTab);
            app.UITableReactions_2.ColumnName = {'Reaction index'; 'Reactants'; 'Products'; 'Reaction type'; 'Reaction Constants'; 'Propensity'};
            app.UITableReactions_2.RowName = {};
            app.EditFieldLabel = uilabel(app.ObservationTab);
            app.EditFieldLabel.Text = 'The function h showing the ideal relation between the observation and system state.';
            app.EditFieldHFunction = uieditfield(app.ObservationTab, 'text');
            app.AddtheobservationButton = uibutton(app.ObservationTab, 'push');
            app.AddtheobservationButton.ButtonPushedFcn = createCallbackFcn(app, @AddtheobservationButtonPushed, true);
            app.AddtheobservationButton.Text = 'Add the observation';
            app.SavethenetworkButton = uibutton(app.ObservationTab, 'push');
            app.SavethenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @SavethenetworkButtonPushed, true);
            app.SavethenetworkButton.Text = 'Save the network';
            app.Label_error_information2 = uilabel(app.ObservationTab);
            app.Label_error_information2.Text = '';
            app.DeletethelastobservationchannelButton = uibutton(app.ObservationTab, 'push');
            app.DeletethelastobservationchannelButton.ButtonPushedFcn = createCallbackFcn(app, @DeletethelastobservationchannelButtonPushed, true);
            app.DeletethelastobservationchannelButton.Text = 'Delete the last observation channel';
            app.ObservationsY_ith_iXtsigmaW_itLabel = uilabel(app.ObservationTab);
            app.ObservationsY_ith_iXtsigmaW_itLabel.Interpreter = 'latex';
            app.ObservationsY_ith_iXtsigmaW_itLabel.Text = 'Observations: $Y_i(t)=h_i(X(t))+\sigma W_i(t)$';
            app.SimulationoftheprocessTab = uitab(app.TabGroup);
            app.SimulationoftheprocessTab.Title = 'Simulation of the process';
            app.UIAxesStatesSpecific = uiaxes(app.SimulationoftheprocessTab);
            xlabel(app.UIAxesStatesSpecific, 'Time')
            ylabel(app.UIAxesStatesSpecific, 'Copy  number')
            zlabel(app.UIAxesStatesSpecific, 'Z')
            app.UIAxesStatesSpecific.ButtonDownFcn = createCallbackFcn(app, @UIAxesStatesSpecificButtonDown, true);
            app.UIAxesStates = uiaxes(app.SimulationoftheprocessTab);
            xlabel(app.UIAxesStates, 'Time')
            ylabel(app.UIAxesStates, 'Copy  number')
            zlabel(app.UIAxesStates, 'Z')
            app.UIAxesObservations = uiaxes(app.SimulationoftheprocessTab);
            xlabel(app.UIAxesObservations, 'Time')
            ylabel(app.UIAxesObservations, 'Light  intensity')
            zlabel(app.UIAxesObservations, 'Z')
            app.UIAxesObservationSpecific = uiaxes(app.SimulationoftheprocessTab);
            xlabel(app.UIAxesObservationSpecific, 'Time')
            ylabel(app.UIAxesObservationSpecific, 'Light  intensity')
            zlabel(app.UIAxesObservationSpecific, 'Z')
            app.NameofthenetworkLabel_3 = uilabel(app.SimulationoftheprocessTab);
            app.NameofthenetworkLabel_3.Text = 'Name of the network: ';
            app.GenerateMatlabscriptsforthenetworkButton = uibutton(app.SimulationoftheprocessTab, 'push');
            app.GenerateMatlabscriptsforthenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatlabscriptsforthenetworkButtonPushed, true);
            app.GenerateMatlabscriptsforthenetworkButton.Text = 'Generate Matlab scripts for the network';
            app.Label_error_information_5 = uilabel(app.SimulationoftheprocessTab);
            app.Label_error_information_5.Text = '';
            app.Label_6 = uilabel(app.SimulationoftheprocessTab);
            app.Label_6.Text = '_______________________________________________________________________________________________________________________________________________';
            app.SimulatetheprocessButton = uibutton(app.SimulationoftheprocessTab, 'push');
            app.SimulatetheprocessButton.ButtonPushedFcn = createCallbackFcn(app, @SimulatetheprocessButtonPushed, true);
            app.SimulatetheprocessButton.Text = 'Simulate the process';
            app.StateDropDownLabel = uilabel(app.SimulationoftheprocessTab);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.SimulationoftheprocessTab);
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            app.ObservationchannelDropDownLabel = uilabel(app.SimulationoftheprocessTab);
            app.ObservationchannelDropDownLabel.Text = 'Observation channel';
            app.ObservationchannelDropDown = uidropdown(app.SimulationoftheprocessTab);
            app.ObservationchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ObservationchannelDropDownValueChanged, true);
            app.TerminalTimeEditFieldLabel = uilabel(app.SimulationoftheprocessTab);
            app.TerminalTimeEditFieldLabel.Text = 'Terminal Time';
            app.TerminalTimeEditField = uieditfield(app.SimulationoftheprocessTab, 'numeric');
            app.UITableInitialConditions = uitable(app.SimulationoftheprocessTab);
            app.UITableInitialConditions.ColumnName = {'Species'; 'Initial conditions'};
            app.UITableInitialConditions.RowName = {};
            app.UITableInitialConditions.ColumnEditable = [false true];
            app.ObservationscollectedeveryEditFieldLabel = uilabel(app.SimulationoftheprocessTab);
            app.ObservationscollectedeveryEditFieldLabel.Text = 'Observations collected every';
            app.ObservationscollectedeveryEditField = uieditfield(app.SimulationoftheprocessTab, 'numeric');
            app.Label_error_information_6 = uilabel(app.SimulationoftheprocessTab);
            app.Label_error_information_6.Text = '';
            app.FSPMCmethodfortheCMETab = uitab(app.TabGroup);
            app.FSPMCmethodfortheCMETab.Title = 'FSP/MC method for the CME';
            app.MCmethodButton = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.MCmethodButton.ButtonPushedFcn = createCallbackFcn(app, @MCmethodButtonPushed, true);
            app.MCmethodButton.Text = 'MC method';
            app.SamplesizeEditFieldLabel = uilabel(app.FSPMCmethodfortheCMETab);
            app.SamplesizeEditFieldLabel.Text = 'Sample size';
            app.SamplesizeEditField = uieditfield(app.FSPMCmethodfortheCMETab, 'numeric');
            app.UITableInitialDistribution = uitable(app.FSPMCmethodfortheCMETab);
            app.UITableInitialDistribution.ColumnName = {'Species'; 'Mean'; 'Variance'};
            app.UITableInitialDistribution.RowName = {};
            app.UITableInitialDistribution.ColumnEditable = [false true true];
            app.InitialDistributionLabel = uilabel(app.FSPMCmethodfortheCMETab);
            app.InitialDistributionLabel.Text = 'Initial Distribution';
            app.EachspeciesareassumedtohavetheinitialdistributionLabel = uilabel(app.FSPMCmethodfortheCMETab);
            app.EachspeciesareassumedtohavetheinitialdistributionLabel.Interpreter = 'latex';
            app.EachspeciesareassumedtohavetheinitialdistributionLabel.Text = {'Each species is assumed to  have the initial distribution $\mu-\sigma^2+\mathcal P(\sigma^2)$, where $\mu$ is the mean, $\sigma$ is the standard deviation, and $\mathcal P$ is the Poisson distribution. '; ''; 'All the species are independent at the initial time'};
            app.TerminalTimeEditField_2Label = uilabel(app.FSPMCmethodfortheCMETab);
            app.TerminalTimeEditField_2Label.Text = 'Terminal Time';
            app.TerminalTimeEditField_2 = uieditfield(app.FSPMCmethodfortheCMETab, 'numeric');
            app.Label_error_information_7 = uilabel(app.FSPMCmethodfortheCMETab);
            app.Label_error_information_7.Text = '';
            app.FSPmethodButton = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.FSPmethodButton.ButtonPushedFcn = createCallbackFcn(app, @FSPmethodButtonPushed, true);
            app.FSPmethodButton.Text = 'FSP method';
            app.NameofthenetworkLabel_4 = uilabel(app.FSPMCmethodfortheCMETab);
            app.NameofthenetworkLabel_4.Text = 'Name of the network: ';
            app.UITableTS_2 = uitable(app.FSPMCmethodfortheCMETab);
            app.UITableTS_2.ColumnName = {'Species'; 'Size of the truncated space'};
            app.UITableTS_2.RowName = {};
            app.UITableTS_2.ColumnEditable = [false true];
            app.TruncationSizeLabel = uilabel(app.FSPMCmethodfortheCMETab);
            app.TruncationSizeLabel.Text = 'Truncation Size';
            app.ShowtheMonteCarloresultButton = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.ShowtheMonteCarloresultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtheMonteCarloresultButtonPushed, true);
            app.ShowtheMonteCarloresultButton.Text = 'Show the Monte Carlo result';
            app.UpdatethesettingButton_decomposition_2 = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.UpdatethesettingButton_decomposition_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatethesettingButton_decomposition_2Pushed, true);
            app.UpdatethesettingButton_decomposition_2.Text = 'Update the setting';
            app.SavetheresultButton = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.SavetheresultButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheresultButtonPushed, true);
            app.SavetheresultButton.Text = 'Save the result';
            app.GenerateMatlabscriptsforCMEsolversButton = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.GenerateMatlabscriptsforCMEsolversButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatlabscriptsforCMEsolversButtonPushed, true);
            app.GenerateMatlabscriptsforCMEsolversButton.Text = 'Generate Matlab scripts for CME solvers';
            app.Label_7 = uilabel(app.FSPMCmethodfortheCMETab);
            app.Label_7.Text = '_______________________________________________________________________________________________________________________________________________';
            app.ShowtheFSPresultButton = uibutton(app.FSPMCmethodfortheCMETab, 'push');
            app.ShowtheFSPresultButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtheFSPresultButtonPushed, true);
            app.ShowtheFSPresultButton.Text = 'Show the FSP result';
            app.ConditionalindependencedecompositionTab = uitab(app.TabGroup);
            app.ConditionalindependencedecompositionTab.Title = 'Conditional independence decomposition';
            app.NameofthenetworkLabel_2 = uilabel(app.ConditionalindependencedecompositionTab);
            app.NameofthenetworkLabel_2.Text = 'Name of the network: ';
            app.UITableTS = uitable(app.ConditionalindependencedecompositionTab);
            app.UITableTS.ColumnName = {'Species'; 'Size of the truncated space'};
            app.UITableTS.RowName = {};
            app.UITableTS.ColumnEditable = [false true];
            app.UpdatethesettingButton_decomposition = uibutton(app.ConditionalindependencedecompositionTab, 'push');
            app.UpdatethesettingButton_decomposition.ButtonPushedFcn = createCallbackFcn(app, @UpdatethesettingButton_decompositionPushed, true);
            app.UpdatethesettingButton_decomposition.Text = 'Update the setting';
            app.ThesizeofeachfollowersubsystemshouldbelessthanEditFieldLabel = uilabel(app.ConditionalindependencedecompositionTab);
            app.ThesizeofeachfollowersubsystemshouldbelessthanEditFieldLabel.Text = 'The size of each follower subsystem should be less than:';
            app.ThesizeofeachfollowersubsystemshouldbelessthanEditField = uieditfield(app.ConditionalindependencedecompositionTab, 'numeric');
            app.Label_error_information_3 = uilabel(app.ConditionalindependencedecompositionTab);
            app.Label_error_information_3.Text = '';
            app.SavethenetworkButton_2 = uibutton(app.ConditionalindependencedecompositionTab, 'push');
            app.SavethenetworkButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavethenetworkButton_2Pushed, true);
            app.SavethenetworkButton_2.Text = 'Save the network';
            app.Label_5 = uilabel(app.ConditionalindependencedecompositionTab);
            app.Label_5.Text = '_______________________________________________________________________________________________________________________________________________';
            app.DothedecompositionofthenetworkButton = uibutton(app.ConditionalindependencedecompositionTab, 'push');
            app.DothedecompositionofthenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DothedecompositionofthenetworkButtonPushed, true);
            app.DothedecompositionofthenetworkButton.Text = 'Do the decomposition of the network';
            app.UITableLeader = uitable(app.ConditionalindependencedecompositionTab);
            app.UITableLeader.ColumnName = {'Leader level species'; 'Leader level reactions'};
            app.UITableLeader.RowName = {};
            app.UITableFollower = uitable(app.ConditionalindependencedecompositionTab);
            app.UITableFollower.ColumnName = {'Index'; 'Species involved'; 'Follower level reactions involved'; 'Leader level reactions interact with it'; 'Observations involved'; 'Size of the truncated space'};
            app.UITableFollower.RowName = {};
            app.FollowersubsystemsLabel = uilabel(app.ConditionalindependencedecompositionTab);
            app.FollowersubsystemsLabel.Text = 'Follower subsystems:';
            app.Label_error_information_4 = uilabel(app.ConditionalindependencedecompositionTab);
            app.Label_error_information_4.Text = '';
            app.RBCMEsolverTab = uitab(app.TabGroup);
            app.RBCMEsolverTab.Title = 'RB-CME solver';
            app.NameofthenetworkLabel_5 = uilabel(app.RBCMEsolverTab);
            app.NameofthenetworkLabel_5.Text = 'Name of the network: ';
            app.UITableTS_3 = uitable(app.RBCMEsolverTab);
            app.UITableTS_3.ColumnName = {'Species'; 'Size of the truncated space'};
            app.UITableTS_3.RowName = {};
            app.UITableTS_3.ColumnEditable = [false true];
            app.TruncationSizeLabel_2 = uilabel(app.RBCMEsolverTab);
            app.TruncationSizeLabel_2.Text = 'Truncation Size';
            app.SamplesizeEditField_2Label = uilabel(app.RBCMEsolverTab);
            app.SamplesizeEditField_2Label.Text = 'Sample size';
            app.SamplesizeEditField_2 = uieditfield(app.RBCMEsolverTab, 'numeric');
            app.TerminalTimeEditField_3Label = uilabel(app.RBCMEsolverTab);
            app.TerminalTimeEditField_3Label.Text = 'Terminal Time';
            app.TerminalTimeEditField_3 = uieditfield(app.RBCMEsolverTab, 'numeric');
            app.GenerateMatlabscriptsfortheRBCMEsolverButton = uibutton(app.RBCMEsolverTab, 'push');
            app.GenerateMatlabscriptsfortheRBCMEsolverButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatlabscriptsfortheRBCMEsolverButtonPushed, true);
            app.GenerateMatlabscriptsfortheRBCMEsolverButton.Text = 'Generate Matlab scripts for the RB-CME solver';
            app.UpdatethesettingButton_decomposition_3 = uibutton(app.RBCMEsolverTab, 'push');
            app.UpdatethesettingButton_decomposition_3.ButtonPushedFcn = createCallbackFcn(app, @UpdatethesettingButton_decomposition_3Pushed, true);
            app.UpdatethesettingButton_decomposition_3.Text = 'Update the setting';
            app.UpdatethesettingButton_decomposition_4 = uibutton(app.RBCMEsolverTab, 'push');
            app.UpdatethesettingButton_decomposition_4.ButtonPushedFcn = createCallbackFcn(app, @UpdatethesettingButton_decomposition_4Pushed, true);
            app.UpdatethesettingButton_decomposition_4.Text = 'RB-CME Solver';
            app.UpdatethesettingButton_decomposition_5 = uibutton(app.RBCMEsolverTab, 'push');
            app.UpdatethesettingButton_decomposition_5.ButtonPushedFcn = createCallbackFcn(app, @UpdatethesettingButton_decomposition_5Pushed, true);
            app.UpdatethesettingButton_decomposition_5.Text = 'Show the result';
            app.UITableInitialDistribution_2 = uitable(app.RBCMEsolverTab);
            app.UITableInitialDistribution_2.ColumnName = {'Species'; 'Mean'; 'Variance'};
            app.UITableInitialDistribution_2.RowName = {};
            app.UITableInitialDistribution_2.ColumnEditable = [false true true];
            app.InitialDistributionLabel_2 = uilabel(app.RBCMEsolverTab);
            app.InitialDistributionLabel_2.Text = 'Initial Distribution';
            app.EachspeciesareassumedtohavetheinitialdistributionLabel_2 = uilabel(app.RBCMEsolverTab);
            app.EachspeciesareassumedtohavetheinitialdistributionLabel_2.Interpreter = 'latex';
            app.EachspeciesareassumedtohavetheinitialdistributionLabel_2.Text = {'Each species is assumed to  have the initial distribution $\mu-\sigma^2+\mathcal P(\sigma^2)$, where $\mu$ is the mean, $\sigma$ is the standard deviation, and $\mathcal P$ is the Poisson distribution. '; ''; 'All the species are independent at the initial time'};
            app.Label_error_information_8 = uilabel(app.RBCMEsolverTab);
            app.Label_error_information_8.Text = '';
            app.StochasticfilterTab = uitab(app.TabGroup);
            app.StochasticfilterTab.Title = 'Stochastic filter';
            app.TruncationSizeLabel_3 = uilabel(app.StochasticfilterTab);
            app.TruncationSizeLabel_3.Text = 'Truncation Size';
            app.UITableInitialDistribution_3 = uitable(app.StochasticfilterTab);
            app.UITableInitialDistribution_3.ColumnName = {'Species'; 'Mean'; 'Variance'};
            app.UITableInitialDistribution_3.RowName = {};
            app.UITableInitialDistribution_3.ColumnEditable = [false true true];
            app.InitialDistributionLabel_3 = uilabel(app.StochasticfilterTab);
            app.InitialDistributionLabel_3.Text = 'Initial Distribution';
            app.EachspeciesareassumedtohavetheinitialdistributionLabel_3 = uilabel(app.StochasticfilterTab);
            app.EachspeciesareassumedtohavetheinitialdistributionLabel_3.Interpreter = 'latex';
            app.EachspeciesareassumedtohavetheinitialdistributionLabel_3.Text = {'Each species is assumed to  have the initial distribution $\mu-\sigma^2+\mathcal P(\sigma^2)$, where $\mu$ is the mean, $\sigma$ is the standard deviation, and $\mathcal P$ is the Poisson distribution. '; ''; 'All the species are independent at the initial time'};
            app.SamplesizeEditField_3Label = uilabel(app.StochasticfilterTab);
            app.SamplesizeEditField_3Label.Text = 'Sample size';
            app.SamplesizeEditField_3 = uieditfield(app.StochasticfilterTab, 'numeric');
            app.UITableTS_4 = uitable(app.StochasticfilterTab);
            app.UITableTS_4.ColumnName = {'Species'; 'Size of the truncated space'};
            app.UITableTS_4.RowName = {};
            app.UITableTS_4.ColumnEditable = [false true];
            app.ObservationscollectedeveryEditField_2Label = uilabel(app.StochasticfilterTab);
            app.ObservationscollectedeveryEditField_2Label.Text = 'Observations collected every';
            app.ObservationscollectedeveryEditField_2 = uieditfield(app.StochasticfilterTab, 'numeric');
            app.UITableObservation_2 = uitable(app.StochasticfilterTab);
            app.UITableObservation_2.ColumnName = {'Time'; 'Value'};
            app.UITableObservation_2.RowName = {};
            app.UITableObservation_2.ColumnEditable = [false true];
            app.NameofthenetworkLabel_6 = uilabel(app.StochasticfilterTab);
            app.NameofthenetworkLabel_6.Text = 'Name of the network: ';
            app.ObservationsLabel = uilabel(app.StochasticfilterTab);
            app.ObservationsLabel.Text = 'Observations';
            app.UpdatethesettingButton_decomposition_6 = uibutton(app.StochasticfilterTab, 'push');
            app.UpdatethesettingButton_decomposition_6.ButtonPushedFcn = createCallbackFcn(app, @UpdatethesettingButton_decomposition_6Pushed, true);
            app.UpdatethesettingButton_decomposition_6.Text = 'Update the setting';
            app.Label_error_information_9 = uilabel(app.StochasticfilterTab);
            app.Label_error_information_9.Text = '';
            app.TerminalTimeEditField_4Label = uilabel(app.StochasticfilterTab);
            app.TerminalTimeEditField_4Label.Text = 'Terminal Time';
            app.TerminalTimeEditField_4 = uieditfield(app.StochasticfilterTab, 'numeric');
            app.RaoBlackwellsolverforLabel = uilabel(app.UIFigure);
            app.RaoBlackwellsolverforLabel.Text = 'Rao-Blackwell method for the simulation and Bayesian inference of stochastic chemical reaction networks ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RB_solver_for_SRN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end